classdef GUIforFEA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DisplayDeformedStructureButton  matlab.ui.control.Button
        PostProcessButton               matlab.ui.control.Button
        SolveButton                     matlab.ui.control.Button
        DisplayGivenPlateButton         matlab.ui.control.Button
        PreProcessButton                matlab.ui.control.Button
        GetDataButton                   matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = public)
        Property % Description

        n_element
        n_nodes
        E
        A
        ncon
        X
        Y
        NDU
        dzero
        F
        v
        t
        KE
        K
        KM
        U
        Sx
        Sy
        Sxy

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetDataButton
        function GetDataButtonPushed(app, event)


            clc
            array = readmatrix('data_structure.xlsx');
            data = array;

            app.n_element = data(1,1);
            app.n_nodes = data(1,2);
            app.E = data(1,8);
            app.A = data(1,9);
            app.ncon = [data(:,3),data(:,4),data(:,5)];
            app.X = data(:,6);
            app.Y = data(:,7);
            app.NDU = data(1,11);
            app.dzero = data(:,12);
            app.F = data(:,10);
            app.v = data(1,13);
            app.t = data(1,14);
        end

        % Button pushed function: PreProcessButton
        function PreProcessButtonPushed(app, event)

            n_element = app.n_element;
            n_nodes = app.n_nodes;
            E = app.E;
            A = app.A; %Dummy
            ncon = app.ncon;
            X = app.X;
            Y = app.Y;
            NDU = app.NDU;
            dzero = app.dzero ;
            F = app.F;
            v = app.v;
            t = app.t;


            % Initiate Matricies
            KE = zeros(6);
            K = zeros(2*n_nodes);

            % Main Routine
            for i=1:n_element
                n1 = ncon(i,1);
                n2 = ncon(i,2);
                n3 = ncon(i,3);

                x1 = X(n1);
                x2 = X(n2);
                x3 = X(n3);

                y1 = Y(n1);
                y2 = Y(n2);
                y3 = Y(n3);

                b1 = (y2 - y3);
                b2 = (y3 - y1);
                b3 = (y1 - y2);

                c1 = (x3 - x2);
                c2 = (x1 - x3);
                c3 = (x2 - x1);

                B = (1/(2*A))*[b1 0 b2 0 b3 0
                    0 c1 0 c2 0 c3
                    c1 b1 c2 b2 c3 b3];

                D = (E/(1-v^2))*[1 v 0
                    v 1 0
                    0 0 ((1-v)/2)];

                KE = t*A*(B.')*D*B;
                % Assembles Overall Stiffness Matrix
                n1 = ncon(i,1);
                n2 = ncon(i,2);
                n3 = ncon(i,3);

                ROC(1) = (2*n1)-1;
                ROC(2) = (2*n1);
                ROC(3) = (2*n2)-1;
                ROC(4) = (2*n2);
                ROC(5) = (2*n3)-1;
                ROC(6) = (2*n3);

                for IX = 1:6
                    MI = ROC(IX);
                    for JX = 1:6
                        MJ = ROC(JX);

                        K(MI,MJ) = K(MI,MJ) + KE(IX,JX);
                    end
                end
            end

            app.K = K;
        end

        % Button pushed function: DisplayGivenPlateButton
        function DisplayGivenPlateButtonPushed(app, event)

            n_element = app.n_element;
            n_nodes = app.n_nodes;
            E = app.E;
            A = app.A; %Dummy
            ncon = app.ncon;
            X = app.X;
            Y = app.Y;
            NDU = app.NDU;
            dzero = app.dzero ;
            F = app.F;
            v = app.v;
            t = app.t;


            for i = 1:n_element

                n1 = ncon(i,1);
                n2 = ncon(i,2);
                n3 = ncon(i,3);

                x1 = X(n1);
                x2 = X(n2);
                x3 = X(n3);
                x4 = x1;

                y1 = Y(n1);
                y2 = Y(n2);
                y3 = Y(n3);
                y4 = y1;

                %Display Structures onto graphs
                plot(app.UIAxes,[x1 x2 x3 x4],[y1 y2 y3 y4],'LineWidth',5,'Color','red')
                hold(app.UIAxes,'on');

                plot(app.UIAxes,[x1 x2 x3 x4],[y1 y2 y3 y4],'LineWidth',2);
                hold(app.UIAxes,'on');

            end
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)

            n_element = app.n_element;
            n_nodes = app.n_nodes;
            E = app.E;
            A = app.A; %Dummy
            ncon = app.ncon;
            X = app.X;
            Y = app.Y;
            NDU = app.NDU;
            dzero = app.dzero ;
            F = app.F;
            v = app.v;
            t = app.t;
            K=app.K;

            KM = K

            for k = 1:NDU
                n = dzero(k);
                KM(n,:) = 0;
            end

            for k = 1:NDU
                n = dzero(k);
                KM(:,n) = 0;
            end

            for k = 1:NDU
                n = dzero(k);
                KM(n,n) = KM(n,n) +1;
            end

            U = inv(KM)*F;

            for i = 1:n_element
                n1 = ncon(i,1);
                n2 = ncon(i,2);
                n3 = ncon(i,3);

                x1 = X(n1);
                x2 = X(n2);
                x3 = X(n3);

                y1 = Y(n1);
                y2 = Y(n2);
                y3 = Y(n3);

                b1 = (y2 - y3);
                b2 = (y3 - y1);
                b3 = (y1 - y2);

                c1 = (x3 - x2);
                c2 = (x1 - x3);
                c3 = (x2 - x1);

                B = (1/(2*A))*[b1 0 b2 0 b3 0
                    0 c1 0 c2 0 c3
                    c1 b1 c2 b2 c3 b3];

                D = (E/(1-v^2))*[1 v 0
                    v 1 0
                    0 0 ((1-v)/2)];

                u1 = U(2*n1-1);
                v1 = U(2*n1);
                u2 = U(2*n2-1);
                v2 = U(2*n2);
                u3 = U(2*n3-1);
                v3 = U(2*n3);

                d = [u1;v1;u2;v2;u3;v3];
                e = B*d;
                Sigma = D*e;
                Sx(i) = Sigma(1);
                Sy(i) = Sigma(2);
                Sxy(i) = Sigma(3);

            end

            Sx = Sx.';
            Sy = Sy.';
            Sxy = Sxy.';

            app.U = U;
            app.Sx = Sx;
            app.Sy = Sy;
            app.Sxy = Sxy;

        end

        % Button pushed function: DisplayDeformedStructureButton
        function DisplayDeformedStructureButtonPushed(app, event)

            n_element = app.n_element;
            n_nodes = app.n_nodes;
            E = app.E;
            A = app.A; %Dummy
            ncon = app.ncon;
            X = app.X;
            Y = app.Y;
            NDU = app.NDU;
            dzero = app.dzero ;
            F = app.F;
            v = app.v;
            t = app.t;
            K=app.K;
            U = app.U;


            for i = 1:n_element

                n1 = ncon(i,1);
                n2 = ncon(i,2);
                n3 = ncon(i,3);

                x1 = X(n1);
                x2 = X(n2);
                x3 = X(n3);
                x4 = x1;

                y1 = Y(n1);
                y2 = Y(n2);
                y3 = Y(n3);
                y4 = y1;

                u1 = U(2*n1-1);
                v1 = U(2*n1);
                u2 = U(2*n2-1);
                v2 = U(2*n2);
                u3 = U(2*n3-1);
                v3 = U(2*n3);

                xf1 = X(n1) + 10000*u1;
                xf2 = X(n2) + 10000*u2;
                xf3 = X(n3) + 10000*u3;
                xf4 = x1;

                yf1 = Y(n1) + 10000*v1;
                yf2 = Y(n2) + 10000*v2;
                yf3 = Y(n3) + 10000*v3;
                yf4 = y1;

                plot(app.UIAxes_2,[xf1 xf2 xf3 xf4],[yf1 yf2 yf3 yf4],'LineWidth',5,'Color','red')
                hold (app.UIAxes_2,'on');

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Given Plate')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Given Plate')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.GetDataButton = uibutton(app.UIFigure, 'push');
            app.GetDataButton.ButtonPushedFcn = createCallbackFcn(app, @GetDataButtonPushed, true);
            app.GetDataButton.Text = 'Get Data';
            app.PreProcessButton = uibutton(app.UIFigure, 'push');
            app.PreProcessButton.ButtonPushedFcn = createCallbackFcn(app, @PreProcessButtonPushed, true);
            app.PreProcessButton.Text = 'Pre Process';
            app.DisplayGivenPlateButton = uibutton(app.UIFigure, 'push');
            app.DisplayGivenPlateButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayGivenPlateButtonPushed, true);
            app.DisplayGivenPlateButton.Text = 'Display Given Plate';
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.PostProcessButton = uibutton(app.UIFigure, 'push');
            app.PostProcessButton.Text = 'Post Process';
            app.DisplayDeformedStructureButton = uibutton(app.UIFigure, 'push');
            app.DisplayDeformedStructureButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayDeformedStructureButtonPushed, true);
            app.DisplayDeformedStructureButton.Text = 'Display Deformed Structure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIforFEA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end